classdef ABBRobotGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ABBRobotGUIUIFigure    matlab.ui.Figure
        SavedP3Label           matlab.ui.control.Label
        SavedP2Label           matlab.ui.control.Label
        SavedP1Label           matlab.ui.control.Label
        P3Label                matlab.ui.control.Label
        P2Label                matlab.ui.control.Label
        P1Label                matlab.ui.control.Label
        GOP3Button             matlab.ui.control.Button
        GOP2Button             matlab.ui.control.Button
        GOP1Button             matlab.ui.control.Button
        SaveP3Button           matlab.ui.control.Button
        SaveP2Button           matlab.ui.control.Button
        SaveP1Button           matlab.ui.control.Button
        HOMEButton             matlab.ui.control.Button
        T6ValueEditField       matlab.ui.control.NumericEditField
        T6ValueEditFieldLabel  matlab.ui.control.Label
        T5ValueEditField       matlab.ui.control.NumericEditField
        T5ValueEditFieldLabel  matlab.ui.control.Label
        T4ValueEditField       matlab.ui.control.NumericEditField
        T4ValueEditFieldLabel  matlab.ui.control.Label
        T3ValueEditField       matlab.ui.control.NumericEditField
        T3ValueEditFieldLabel  matlab.ui.control.Label
        T2ValueEditField       matlab.ui.control.NumericEditField
        T2ValueEditFieldLabel  matlab.ui.control.Label
        T1ValueEditField       matlab.ui.control.NumericEditField
        T1ValueEditFieldLabel  matlab.ui.control.Label
        GOButton               matlab.ui.control.Button
        Theta6Slider           matlab.ui.control.Slider
        Theta6Label            matlab.ui.control.Label
        Theta5Slider           matlab.ui.control.Slider
        Theta5Label            matlab.ui.control.Label
        Theta4Slider           matlab.ui.control.Slider
        Theta4Label            matlab.ui.control.Label
        Theta3Slider           matlab.ui.control.Slider
        Theta3Label            matlab.ui.control.Label
        Theta2Slider           matlab.ui.control.Slider
        Theta2Label            matlab.ui.control.Label
        Theta1Slider           matlab.ui.control.Slider
        Theta1Label            matlab.ui.control.Label
        axes_arm               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices
        link4Patch
        link4Vertices
        link5Patch
        link5Vertices
        link6Patch
        link6Vertices
        jointAngles = [0 0 0 0 0 0];

        savedAngles1 = [0 0 0 0 0 0];
        savedAngles2 = [0 0 0 0 0 0];
        savedAngles3 = [0 0 0 0 0 0];
    end
    
    methods (Access = private)
        
        function updateArm(app)
            
            % TODO: Create the A homogeneous transformation matrices for the given jointAngles.
            A1 = create_A_matrix(0, 0, -90, app.jointAngles(1));
            A2 = create_A_matrix(270, 0, 0, app.jointAngles(2) -90);
            A3 = create_A_matrix(70, 0, -90, app.jointAngles(3));
            A4 = create_A_matrix(0, 302, 90, app.jointAngles(4));
            A5 = create_A_matrix(0, 0, -90, app.jointAngles(5));
            A6 = create_A_matrix(0, 72, 0, app.jointAngles(6));
            
            % TODO: Use the current A matricies to form the T0_n matricies.
            T0_1 = A1;
            T0_2 = A1*A2;
            T0_3 = A1*A2*A3;
            T0_4 = T0_3*A4;
            T0_5 = T0_4*A5;
            T0_6 = T0_5*A6;
            
            % TODO: Use the current T matricies to transform the original patch vertices
            link1VerticesWRTground = T0_1 * app.link1Vertices;
            link2VerticesWRTground = T0_2 * app.link2Vertices;
            link3VerticesWRTground = T0_3 * app.link3Vertices;
            link4VerticesWRTground = T0_4 * app.link4Vertices;
            link5VerticesWRTground = T0_5 * app.link5Vertices;
            link6VerticesWRTground = T0_6 * app.link6Vertices;

            % TODO: Update the patches with the newly transformed vertices
            set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');
            set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');
            set(app.link4Patch,'Vertices', link4VerticesWRTground(1:3,:)');
            set(app.link5Patch,'Vertices', link5VerticesWRTground(1:3,:)');
            set(app.link6Patch,'Vertices', link6VerticesWRTground(1:3,:)');

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc

            % Prepare the axes_arm properties
            app.axes_arm.View = [37.5 30];
            app.axes_arm.Visible = 'off';
            
            % Once you are in Matlab App Designer you can try these axes_arm properties too.
            % These properties keep the Axes from automatically resizing (optional)
            % app.axes_arm.XLim = [-160 250];
            % app.axes_arm.YLim = [-250 250];
            % app.axes_arm.ZLim = [-22 22];
            
            % Create vertices for all the patches
            makeStlLink("Link0.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
            % Save references to the vertices of each patch, make points 4x1 not 3x1.
            app.link1Patch = makeStlLink("Link1.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));
            
            app.link2Patch = makeStlLink("Link2.stl", app.axes_arm, [198/255 172/255 20/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));
            
            app.link3Patch = makeStlLink("Link3.stl", app.axes_arm, [181/255 24/255 35/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));

            app.link4Patch = makeStlLink("Link4.stl", app.axes_arm, [000/255 255/255 00/255]);
            app.link4Vertices = get(app.link4Patch, 'Vertices')';
            app.link4Vertices(4,:) = ones(1, size(app.link4Vertices,2));

            app.link5Patch = makeStlLink("Link5.stl", app.axes_arm, [181/255 24/255 100/255]);
            app.link5Vertices = get(app.link5Patch, 'Vertices')';
            app.link5Vertices(4,:) = ones(1, size(app.link5Vertices,2));

            app.link6Patch = makeStlLink("Link6.stl", app.axes_arm, [000/255 100/255 255/255]);
            app.link6Vertices = get(app.link6Patch, 'Vertices')';
            app.link6Vertices(4,:) = ones(1, size(app.link6Vertices,2));

            app.updateArm
        end

        % Value changing function: Theta1Slider
        function Theta1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(1) = changingValue;
            % TODO also update numeric value text field
            app.T1ValueEditField.Value = app.jointAngles(1);
            app.updateArm
            
        end

        % Value changing function: Theta2Slider
        function Theta2SliderValueChanging(app, event)
             changingValue = event.Value;
             app.jointAngles(2) = changingValue;
             app.T2ValueEditField.Value = app.jointAngles(2);
             app.updateArm
                
        end

        % Value changing function: Theta3Slider
        function Theta3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(3) = changingValue;
            app.T3ValueEditField.Value = app.jointAngles(3);
            app.updateArm
            
        end

        % Value changing function: Theta4Slider
        function Theta4SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(4) = changingValue;
            app.T4ValueEditField.Value = app.jointAngles(4);
            app.updateArm
            
        end

        % Value changing function: Theta5Slider
        function Theta5SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(5) = changingValue;
            app.T5ValueEditField.Value = app.jointAngles(5);
            app.updateArm
            
        end

        % Value changing function: Theta6Slider
        function Theta6SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(6) = changingValue;
            app.T6ValueEditField.Value = app.jointAngles(6);
            app.updateArm
            
        end

        % Value changed function: T1ValueEditField
        function T1ValueEditFieldValueChanged(app, event)
            value = app.T1ValueEditField.Value;
            
        end

        % Value changed function: T2ValueEditField
        function T2ValueEditFieldValueChanged(app, event)
            value = app.T2ValueEditField.Value;
            
        end

        % Value changed function: T3ValueEditField
        function T3ValueEditFieldValueChanged(app, event)
            value = app.T3ValueEditField.Value;
            
        end

        % Value changed function: T4ValueEditField
        function T4ValueEditFieldValueChanged(app, event)
            value = app.T4ValueEditField.Value;
            
        end

        % Value changed function: T5ValueEditField
        function T5ValueEditFieldValueChanged(app, event)
            value = app.T5ValueEditField.Value;
            
        end

        % Value changed function: T6ValueEditField
        function T6ValueEditFieldValueChanged(app, event)
            value = app.T6ValueEditField.Value;
            
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            joint1Value = app.T1ValueEditField.Value;
            app.jointAngles(1) = joint1Value;
            joint2Value = app.T2ValueEditField.Value;
            app.jointAngles(2) = joint2Value;
            joint3Value = app.T3ValueEditField.Value;
            app.jointAngles(3) = joint3Value;
            joint4Value = app.T4ValueEditField.Value;
            app.jointAngles(4) = joint4Value;
            joint5Value = app.T5ValueEditField.Value;
            app.jointAngles(5) = joint5Value;
            joint6Value = app.T6ValueEditField.Value;
            app.jointAngles(6) = joint6Value;
            
            app.Theta1Slider.Value = joint1Value;
            app.Theta2Slider.Value = joint2Value;
            app.Theta3Slider.Value = joint3Value;
            app.Theta4Slider.Value = joint4Value;
            app.Theta5Slider.Value = joint5Value;
            app.Theta6Slider.Value = joint6Value;
            app.updateArm;
        end

        % Button pushed function: HOMEButton
        function HOMEButtonPushed(app, event)
            app.jointAngles(1:6) = [0 0 0 0 0 0];
            app.Theta1Slider.Value = 0;
            app.Theta2Slider.Value = 0;
            app.Theta3Slider.Value = 0;
            app.Theta4Slider.Value = 0;
            app.Theta5Slider.Value = 0;
            app.Theta6Slider.Value = 0;

            app.T1ValueEditField.Value = 0;
            app.T2ValueEditField.Value = 0;
            app.T3ValueEditField.Value = 0;
            app.T4ValueEditField.Value = 0;
            app.T5ValueEditField.Value = 0;
            app.T6ValueEditField.Value = 0;

            app.updateArm;
        end

        % Button pushed function: SaveP1Button
        function SaveP1ButtonPushed(app, event)
            app.savedAngles1 = app.jointAngles(1:6);
            str = sprintf("[ %.1f %.1f %.1f %.1f %.1f %.1f]", app.savedAngles1);
            app.SavedP1Label.Text = str;
        end

        % Button pushed function: SaveP2Button
        function SaveP2ButtonPushed(app, event)
            app.savedAngles2 = app.jointAngles(1:6);
            str = sprintf("[ %.1f %.1f %.1f %.1f %.1f %.1f]", app.savedAngles2);
            app.SavedP2Label.Text = str;
        end

        % Button pushed function: SaveP3Button
        function SaveP3ButtonPushed(app, event)
            app.savedAngles3 = app.jointAngles(1:6);
            str = sprintf("[ %.1f %.1f %.1f %.1f %.1f %.1f]", app.savedAngles3);
            app.SavedP3Label.Text = str;
        end

        % Button pushed function: GOP1Button
        function GOP1ButtonPushed(app, event)
            app.jointAngles(1:6) = app.savedAngles1;

            app.T1ValueEditField.Value = app.jointAngles(1);
            app.T2ValueEditField.Value = app.jointAngles(2);
            app.T3ValueEditField.Value = app.jointAngles(3);
            app.T4ValueEditField.Value = app.jointAngles(4);
            app.T5ValueEditField.Value = app.jointAngles(5);
            app.T6ValueEditField.Value = app.jointAngles(6);
            
            app.Theta1Slider.Value = app.jointAngles(1);
            app.Theta2Slider.Value = app.jointAngles(2);
            app.Theta3Slider.Value = app.jointAngles(3);
            app.Theta4Slider.Value = app.jointAngles(4);
            app.Theta5Slider.Value = app.jointAngles(5);
            app.Theta6Slider.Value = app.jointAngles(6);

            app.updateArm;
        end

        % Button pushed function: GOP2Button
        function GOP2ButtonPushed(app, event)
            app.jointAngles(1:6) = app.savedAngles2;

            app.T1ValueEditField.Value = app.jointAngles(1);
            app.T2ValueEditField.Value = app.jointAngles(2);
            app.T3ValueEditField.Value = app.jointAngles(3);
            app.T4ValueEditField.Value = app.jointAngles(4);
            app.T5ValueEditField.Value = app.jointAngles(5);
            app.T6ValueEditField.Value = app.jointAngles(6);
            
            app.Theta1Slider.Value = app.jointAngles(1);
            app.Theta2Slider.Value = app.jointAngles(2);
            app.Theta3Slider.Value = app.jointAngles(3);
            app.Theta4Slider.Value = app.jointAngles(4);
            app.Theta5Slider.Value = app.jointAngles(5);
            app.Theta6Slider.Value = app.jointAngles(6);

            app.updateArm;
        end

        % Button pushed function: GOP3Button
        function GOP3ButtonPushed(app, event)
            app.jointAngles(1:6) = app.savedAngles3;

            app.T1ValueEditField.Value = app.jointAngles(1);
            app.T2ValueEditField.Value = app.jointAngles(2);
            app.T3ValueEditField.Value = app.jointAngles(3);
            app.T4ValueEditField.Value = app.jointAngles(4);
            app.T5ValueEditField.Value = app.jointAngles(5);
            app.T6ValueEditField.Value = app.jointAngles(6);
            
            app.Theta1Slider.Value = app.jointAngles(1);
            app.Theta2Slider.Value = app.jointAngles(2);
            app.Theta3Slider.Value = app.jointAngles(3);
            app.Theta4Slider.Value = app.jointAngles(4);
            app.Theta5Slider.Value = app.jointAngles(5);
            app.Theta6Slider.Value = app.jointAngles(6);

            app.updateArm;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ABBRobotGUIUIFigure = uifigure('Visible', 'off');
            app.ABBRobotGUIUIFigure.Name = 'ABB Robot GUI';
            app.axes_arm = uiaxes(app.ABBRobotGUIUIFigure);
            title(app.axes_arm, 'Title')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.Theta1Label = uilabel(app.ABBRobotGUIUIFigure);
            app.Theta1Label.Text = 'Theta 1:';
            app.Theta1Slider = uislider(app.ABBRobotGUIUIFigure);
            app.Theta1Slider.Limits = [-90 90];
            app.Theta1Slider.MajorTicks = [-90 -60 -30 0 30 60 90];
            app.Theta1Slider.ValueChangingFcn = createCallbackFcn(app, @Theta1SliderValueChanging, true);
            app.Theta2Label = uilabel(app.ABBRobotGUIUIFigure);
            app.Theta2Label.Text = 'Theta 2:';
            app.Theta2Slider = uislider(app.ABBRobotGUIUIFigure);
            app.Theta2Slider.Limits = [-10 40];
            app.Theta2Slider.MajorTicks = [-10 0 10 20 30 40];
            app.Theta2Slider.ValueChangingFcn = createCallbackFcn(app, @Theta2SliderValueChanging, true);
            app.Theta2Slider.Value = 10;
            app.Theta3Label = uilabel(app.ABBRobotGUIUIFigure);
            app.Theta3Label.Text = 'Theta 3:';
            app.Theta3Slider = uislider(app.ABBRobotGUIUIFigure);
            app.Theta3Slider.Limits = [-10 50];
            app.Theta3Slider.MajorTicks = [-10 0 10 20 30 40 50];
            app.Theta3Slider.ValueChangingFcn = createCallbackFcn(app, @Theta3SliderValueChanging, true);
            app.Theta4Label = uilabel(app.ABBRobotGUIUIFigure);
            app.Theta4Label.Text = 'Theta 4:';
            app.Theta4Slider = uislider(app.ABBRobotGUIUIFigure);
            app.Theta4Slider.Limits = [-90 90];
            app.Theta4Slider.MajorTicks = [-90 -60 -30 0 30 60 90];
            app.Theta4Slider.ValueChangingFcn = createCallbackFcn(app, @Theta4SliderValueChanging, true);
            app.Theta5Label = uilabel(app.ABBRobotGUIUIFigure);
            app.Theta5Label.Text = 'Theta 5:';
            app.Theta5Slider = uislider(app.ABBRobotGUIUIFigure);
            app.Theta5Slider.Limits = [-90 90];
            app.Theta5Slider.MajorTicks = [-90 -60 -30 0 30 60 90];
            app.Theta5Slider.ValueChangingFcn = createCallbackFcn(app, @Theta5SliderValueChanging, true);
            app.Theta6Label = uilabel(app.ABBRobotGUIUIFigure);
            app.Theta6Label.Text = 'Theta 6:';
            app.Theta6Slider = uislider(app.ABBRobotGUIUIFigure);
            app.Theta6Slider.Limits = [-180 180];
            app.Theta6Slider.MajorTicks = [-180 -120 -60 0 60 120 180];
            app.Theta6Slider.ValueChangingFcn = createCallbackFcn(app, @Theta6SliderValueChanging, true);
            app.Theta6Slider.Step = 0.36;
            app.GOButton = uibutton(app.ABBRobotGUIUIFigure, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.T1ValueEditFieldLabel = uilabel(app.ABBRobotGUIUIFigure);
            app.T1ValueEditFieldLabel.Text = 'T1 Value:';
            app.T1ValueEditField = uieditfield(app.ABBRobotGUIUIFigure, 'numeric');
            app.T1ValueEditField.ValueChangedFcn = createCallbackFcn(app, @T1ValueEditFieldValueChanged, true);
            app.T2ValueEditFieldLabel = uilabel(app.ABBRobotGUIUIFigure);
            app.T2ValueEditFieldLabel.Text = 'T2 Value:';
            app.T2ValueEditField = uieditfield(app.ABBRobotGUIUIFigure, 'numeric');
            app.T2ValueEditField.ValueChangedFcn = createCallbackFcn(app, @T2ValueEditFieldValueChanged, true);
            app.T3ValueEditFieldLabel = uilabel(app.ABBRobotGUIUIFigure);
            app.T3ValueEditFieldLabel.Text = 'T3 Value:';
            app.T3ValueEditField = uieditfield(app.ABBRobotGUIUIFigure, 'numeric');
            app.T3ValueEditField.ValueChangedFcn = createCallbackFcn(app, @T3ValueEditFieldValueChanged, true);
            app.T4ValueEditFieldLabel = uilabel(app.ABBRobotGUIUIFigure);
            app.T4ValueEditFieldLabel.Text = 'T4 Value:';
            app.T4ValueEditField = uieditfield(app.ABBRobotGUIUIFigure, 'numeric');
            app.T4ValueEditField.ValueChangedFcn = createCallbackFcn(app, @T4ValueEditFieldValueChanged, true);
            app.T5ValueEditFieldLabel = uilabel(app.ABBRobotGUIUIFigure);
            app.T5ValueEditFieldLabel.Text = 'T5 Value:';
            app.T5ValueEditField = uieditfield(app.ABBRobotGUIUIFigure, 'numeric');
            app.T5ValueEditField.ValueChangedFcn = createCallbackFcn(app, @T5ValueEditFieldValueChanged, true);
            app.T6ValueEditFieldLabel = uilabel(app.ABBRobotGUIUIFigure);
            app.T6ValueEditFieldLabel.Text = 'T6 Value:';
            app.T6ValueEditField = uieditfield(app.ABBRobotGUIUIFigure, 'numeric');
            app.T6ValueEditField.ValueChangedFcn = createCallbackFcn(app, @T6ValueEditFieldValueChanged, true);
            app.HOMEButton = uibutton(app.ABBRobotGUIUIFigure, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Text = 'HOME';
            app.SaveP1Button = uibutton(app.ABBRobotGUIUIFigure, 'push');
            app.SaveP1Button.ButtonPushedFcn = createCallbackFcn(app, @SaveP1ButtonPushed, true);
            app.SaveP1Button.Text = 'Save P1';
            app.SaveP2Button = uibutton(app.ABBRobotGUIUIFigure, 'push');
            app.SaveP2Button.ButtonPushedFcn = createCallbackFcn(app, @SaveP2ButtonPushed, true);
            app.SaveP2Button.Text = 'Save P2';
            app.SaveP3Button = uibutton(app.ABBRobotGUIUIFigure, 'push');
            app.SaveP3Button.ButtonPushedFcn = createCallbackFcn(app, @SaveP3ButtonPushed, true);
            app.SaveP3Button.Text = 'Save P3';
            app.GOP1Button = uibutton(app.ABBRobotGUIUIFigure, 'push');
            app.GOP1Button.ButtonPushedFcn = createCallbackFcn(app, @GOP1ButtonPushed, true);
            app.GOP1Button.Text = 'GO P1';
            app.GOP2Button = uibutton(app.ABBRobotGUIUIFigure, 'push');
            app.GOP2Button.ButtonPushedFcn = createCallbackFcn(app, @GOP2ButtonPushed, true);
            app.GOP2Button.Text = 'GO P2';
            app.GOP3Button = uibutton(app.ABBRobotGUIUIFigure, 'push');
            app.GOP3Button.ButtonPushedFcn = createCallbackFcn(app, @GOP3ButtonPushed, true);
            app.GOP3Button.Text = 'GO P3';
            app.P1Label = uilabel(app.ABBRobotGUIUIFigure);
            app.P1Label.Text = 'P1:';
            app.P2Label = uilabel(app.ABBRobotGUIUIFigure);
            app.P2Label.Text = 'P2:';
            app.P3Label = uilabel(app.ABBRobotGUIUIFigure);
            app.P3Label.Text = 'P3:';
            app.SavedP1Label = uilabel(app.ABBRobotGUIUIFigure);
            app.SavedP1Label.Text = '[ 0 0 0 ]';
            app.SavedP2Label = uilabel(app.ABBRobotGUIUIFigure);
            app.SavedP2Label.Text = '[ 0 0 0 ]';
            app.SavedP3Label = uilabel(app.ABBRobotGUIUIFigure);
            app.SavedP3Label.Text = '[ 0 0 0 ]';
            % Show the figure after all components are created
            app.ABBRobotGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ABBRobotGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ABBRobotGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ABBRobotGUIUIFigure)
        end
    end
end