classdef AdeeptArmGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdeeptArmGUIUIFigure   matlab.ui.Figure
        SavedP3Label           matlab.ui.control.Label
        SavedP2Label           matlab.ui.control.Label
        SavedP1Label           matlab.ui.control.Label
        P3Label                matlab.ui.control.Label
        P2Label                matlab.ui.control.Label
        P1Label                matlab.ui.control.Label
        GOP3Button             matlab.ui.control.Button
        GOP2Button             matlab.ui.control.Button
        GOP1Button             matlab.ui.control.Button
        SaveP3Button           matlab.ui.control.Button
        SaveP2Button           matlab.ui.control.Button
        SaveP1Button           matlab.ui.control.Button
        HOMEButton             matlab.ui.control.Button
        GOButton               matlab.ui.control.Button
        J3ValueEditField       matlab.ui.control.NumericEditField
        J3ValueEditFieldLabel  matlab.ui.control.Label
        J2ValueEditField       matlab.ui.control.NumericEditField
        J2ValueEditFieldLabel  matlab.ui.control.Label
        J1ValueEditField       matlab.ui.control.NumericEditField
        J1ValueEditFieldLabel  matlab.ui.control.Label
        Joint3Slider           matlab.ui.control.Slider
        Joint3SliderLabel      matlab.ui.control.Label
        Joint2Slider           matlab.ui.control.Slider
        Joint2SliderLabel      matlab.ui.control.Label
        Joint1Slider           matlab.ui.control.Slider
        Joint1SliderLabel      matlab.ui.control.Label
        axes_arm               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices
        jointAngles = [0 0 0];

        savedAngles1 = [0 0 0];
        savedAngles2 = [0 0 0];
        savedAngles3 = [0 0 0];
    end
    
    methods (Access = private)
        
        function updateArm(app)
            
            % TODO: Create the A homogeneous transformation matrices for the given jointAngles.
            A1 = create_A_matrix(64, 0, 0, app.jointAngles(1));
            A2 = create_A_matrix(0, 0, -90, app.jointAngles(2));
            A3 = create_A_matrix(0, 140, 0, app.jointAngles(3));
            
            % TODO: Use the current A matricies to form the T0_n matricies.
            T0_1 = A1;
            T0_2 = A1*A2;
            T0_3 = A1*A2*A3;
            
            % TODO: Use the current T matricies to transform the original patch vertices
            link1VerticesWRTground = T0_1 * app.link1Vertices;
            link2VerticesWRTground = T0_2 * app.link2Vertices;
            link3VerticesWRTground = T0_3 * app.link3Vertices;
            
            % TODO: Update the patches with the newly transformed vertices
            set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');
            set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc

            % Prepare the axes_arm properties
            app.axes_arm.View = [90 -50];
            app.axes_arm.Visible = 'off';
            
            % Once you are in Matlab App Designer you can try these axes_arm properties too.
            % These properties keep the Axes from automatically resizing (optional)
            % app.axes_arm.XLim = [-160 250];
            % app.axes_arm.YLim = [-250 250];
            % app.axes_arm.ZLim = [-22 22];
            
            % Create vertices for all the patches
            makeStlLink("AdeeptL0.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
            % Save references to the vertices of each patch, make points 4x1 not 3x1.
            app.link1Patch = makeStlLink("AdeeptL1.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));
            
            app.link2Patch = makeStlLink("AdeeptL2.stl", app.axes_arm, [198/255 172/255 20/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));
            
            app.link3Patch = makeStlLink("AdeeptL3.stl", app.axes_arm, [181/255 24/255 35/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));

            app.updateArm
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(1) = changingValue;
            % TODO also update numeric value text field
            app.J1ValueEditField.Value = app.jointAngles(1);
            app.updateArm
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            joint1Value = app.J1ValueEditField.Value;
            app.jointAngles(1) = joint1Value;
            joint2Value = app.J2ValueEditField.Value;
            app.jointAngles(2) = joint2Value;
            joint3Value = app.J3ValueEditField.Value;
            app.jointAngles(3) = joint3Value;
            
            app.Joint1Slider.Value = joint1Value;
            app.Joint2Slider.Value = joint2Value;
            app.Joint3Slider.Value = joint3Value;
            app.updateArm;
        end

        % Button pushed function: HOMEButton
        function HOMEButtonPushed(app, event)
            app.jointAngles(1:3) = [0;0;0];
            app.Joint1Slider.Value = 0;
            app.Joint2Slider.Value = 0;
            app.Joint3Slider.Value = 0;

            app.J1ValueEditField.Value = 0;
            app.J2ValueEditField.Value = 0;
            app.J3ValueEditField.Value = 0;
            app.updateArm;
        end

        % Button pushed function: SaveP1Button
        function SaveP1ButtonPushed(app, event)
            app.savedAngles1 = app.jointAngles(1:3);
            str = sprintf("[ %.2f %.2f %.2f ]", app.savedAngles1);
            app.SavedP1Label.Text = str;
        end

        % Button pushed function: SaveP2Button
        function SaveP2ButtonPushed(app, event)
            app.savedAngles2 = app.jointAngles(1:3);
            str = sprintf("[ %.2f %.2f %.2f ]", app.savedAngles2);
            app.SavedP2Label.Text = str;
        end

        % Button pushed function: SaveP3Button
        function SaveP3ButtonPushed(app, event)
            app.savedAngles3 = app.jointAngles(1:3);
            str = sprintf("[ %.2f %.2f %.2f ]", app.savedAngles3);
            app.SavedP3Label.Text = str;
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(2) = changingValue;
            app.J2ValueEditField.Value = app.jointAngles(2);
            app.updateArm
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(3) = changingValue;
            app.J3ValueEditField.Value = app.jointAngles(3);
            app.updateArm
        end

        % Button pushed function: GOP1Button
        function GOP1ButtonPushed(app, event)
            app.jointAngles(1:3) = app.savedAngles1;

            app.J1ValueEditField.Value = app.jointAngles(1);
            app.J2ValueEditField.Value = app.jointAngles(2);
            app.J3ValueEditField.Value = app.jointAngles(3);
            
            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);

            app.updateArm;
        end

        % Button pushed function: GOP2Button
        function GOP2ButtonPushed(app, event)
            app.jointAngles(1:3) = app.savedAngles2;

            app.J1ValueEditField.Value = app.jointAngles(1);
            app.J2ValueEditField.Value = app.jointAngles(2);
            app.J3ValueEditField.Value = app.jointAngles(3);
            
            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);

            app.updateArm;          
        end

        % Button pushed function: GOP3Button
        function GOP3ButtonPushed(app, event)
            app.jointAngles(1:3) = app.savedAngles3;

            app.J1ValueEditField.Value = app.jointAngles(1);
            app.J2ValueEditField.Value = app.jointAngles(2);
            app.J3ValueEditField.Value = app.jointAngles(3);
            
            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);
            app.updateArm;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdeeptArmGUIUIFigure = uifigure('Visible', 'off');
            app.AdeeptArmGUIUIFigure.Name = 'AdeeptArmGUI';
            app.axes_arm = uiaxes(app.AdeeptArmGUIUIFigure);
            title(app.axes_arm, 'Title')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.Joint1SliderLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.AdeeptArmGUIUIFigure);
            app.Joint1Slider.Limits = [-90 90];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint2SliderLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.AdeeptArmGUIUIFigure);
            app.Joint2Slider.Limits = [-90 90];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint3SliderLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.AdeeptArmGUIUIFigure);
            app.Joint3Slider.Limits = [-90 90];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.J1ValueEditFieldLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.J1ValueEditFieldLabel.Text = 'J1 Value';
            app.J1ValueEditField = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.J2ValueEditFieldLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.J2ValueEditFieldLabel.Text = 'J2 Value';
            app.J2ValueEditField = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.J3ValueEditFieldLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.J3ValueEditFieldLabel.Text = 'J3 Value';
            app.J3ValueEditField = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.GOButton = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.HOMEButton = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Text = 'HOME';
            app.SaveP1Button = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.SaveP1Button.ButtonPushedFcn = createCallbackFcn(app, @SaveP1ButtonPushed, true);
            app.SaveP1Button.Text = 'Save P1';
            app.SaveP2Button = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.SaveP2Button.ButtonPushedFcn = createCallbackFcn(app, @SaveP2ButtonPushed, true);
            app.SaveP2Button.Text = 'Save P2';
            app.SaveP3Button = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.SaveP3Button.ButtonPushedFcn = createCallbackFcn(app, @SaveP3ButtonPushed, true);
            app.SaveP3Button.Text = 'Save P3';
            app.GOP1Button = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.GOP1Button.ButtonPushedFcn = createCallbackFcn(app, @GOP1ButtonPushed, true);
            app.GOP1Button.Text = 'GO P1';
            app.GOP2Button = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.GOP2Button.ButtonPushedFcn = createCallbackFcn(app, @GOP2ButtonPushed, true);
            app.GOP2Button.Text = 'GO P2';
            app.GOP3Button = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.GOP3Button.ButtonPushedFcn = createCallbackFcn(app, @GOP3ButtonPushed, true);
            app.GOP3Button.Text = 'GO P3';
            app.P1Label = uilabel(app.AdeeptArmGUIUIFigure);
            app.P1Label.Text = 'P1:';
            app.P2Label = uilabel(app.AdeeptArmGUIUIFigure);
            app.P2Label.Text = 'P2:';
            app.P3Label = uilabel(app.AdeeptArmGUIUIFigure);
            app.P3Label.Text = 'P3:';
            app.SavedP1Label = uilabel(app.AdeeptArmGUIUIFigure);
            app.SavedP1Label.Text = '[ 0 0 0 ]';
            app.SavedP2Label = uilabel(app.AdeeptArmGUIUIFigure);
            app.SavedP2Label.Text = '[ 0 0 0 ]';
            app.SavedP3Label = uilabel(app.AdeeptArmGUIUIFigure);
            app.SavedP3Label.Text = '[ 0 0 0 ]';
            % Show the figure after all components are created
            app.AdeeptArmGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdeeptArmGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdeeptArmGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdeeptArmGUIUIFigure)
        end
    end
end