classdef GoldGuesser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GoldGuesserUIFigure  matlab.ui.Figure
        ImageGold            matlab.ui.control.Image
        ImageLeprechaun      matlab.ui.control.Image
        LabelPercentValue    matlab.ui.control.Label
        LabelSuccessValue    matlab.ui.control.Label
        LabelAttemptsValue   matlab.ui.control.Label
        PercentCorrectLabel  matlab.ui.control.Label
        SuccessLabel         matlab.ui.control.Label
        AttemptsLabel        matlab.ui.control.Label
        Button_3             matlab.ui.control.Button
        Button_2             matlab.ui.control.Button
        Button               matlab.ui.control.Button
    end

    
    properties (Access = public)
        LeprechaunPositions
        GoldPositions
        LephrechaunImageSources
        Attempts = 0
        Successes = 0
        PercentCorrect
    end
    
    methods (Access = private)
        
        function makeAGuess(app,leprechaunPostionIndex)
            app.Attempts = app.Attempts + 1;

            app.ImageLeprechaun.Position = app.LeprechaunPositions(leprechaunPostionIndex,:);
            goldIndex = randi([1,3]);
            app.ImageGold.Position = app.GoldPositions(goldIndex,:);
            if goldIndex == leprechaunPostionIndex
                app.ImageLeprechaun.ImageSource = app.LephrechaunImageSources(1);
                app.Successes = app.Successes + 1;
            else
               app.ImageLeprechaun.ImageSource = app.LephrechaunImageSources(2);
            end
            
            app.LabelAttemptsValue.Text = string(app.Attempts);
            app.LabelSuccessValue.Text = string(app.Successes);
            app.PercentCorrect.Text = num2str(app.Successes / app.Attempts *100 , 1);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.LephrechaunImageSources = ["./LeprechaunHappy.jpg","./LeprechaunSad.jpg"];
            app.LeprechaunPositions(1,:) = [99,238,100,100];
            app.LeprechaunPositions(2,:) = [271,238,100,100];
            app.LeprechaunPositions(3,:) = [442,238,100,100];

            app.GoldPositions(1,:) = [99,36,100,100];
            app.GoldPositions(2,:) = [271,36,100,100];
            app.GoldPositions(3,:) = [442,36,100,100];
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % app.ImageLeprechaun.ImageSource = app.LephrechaunImageSources(1);
            % app.ImageLeprechaun.Position = app.LeprechaunPositions(1,:);
            app.makeAGuess(1)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % app.ImageLeprechaun.ImageSource = app.LephrechaunImageSources(1);
            % app.ImageLeprechaun.Position = app.LeprechaunPositions(2,:);
            app.makeAGuess(2)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            % app.ImageLeprechaun.ImageSource = app.LephrechaunImageSources(2);
            % app.ImageLeprechaun.Position = app.LeprechaunPositions(3,:);
            app.makeAGuess(3)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GoldGuesserUIFigure = uifigure('Visible', 'off');
            app.GoldGuesserUIFigure.Name = 'Gold Guesser';
            app.Button = uibutton(app.GoldGuesserUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.GoldGuesserUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.GoldGuesserUIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.AttemptsLabel = uilabel(app.GoldGuesserUIFigure);
            app.AttemptsLabel.Text = 'Attempts: ';
            app.SuccessLabel = uilabel(app.GoldGuesserUIFigure);
            app.SuccessLabel.Text = 'Success: ';
            app.PercentCorrectLabel = uilabel(app.GoldGuesserUIFigure);
            app.PercentCorrectLabel.Text = 'Percent Correct:';
            app.LabelAttemptsValue = uilabel(app.GoldGuesserUIFigure);
            app.LabelAttemptsValue.Text = '0';
            app.LabelSuccessValue = uilabel(app.GoldGuesserUIFigure);
            app.LabelSuccessValue.Text = '0';
            app.LabelPercentValue = uilabel(app.GoldGuesserUIFigure);
            app.LabelPercentValue.Text = '%';
            app.ImageLeprechaun = uiimage(app.GoldGuesserUIFigure);
            app.ImageLeprechaun.ImageSource = fullfile(pathToMLAPP, 'LeprechaunSad.jpg');
            app.ImageGold = uiimage(app.GoldGuesserUIFigure);
            app.ImageGold.ImageSource = fullfile(pathToMLAPP, 'Gold.jpg');
            % Show the figure after all components are created
            app.GoldGuesserUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GoldGuesser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GoldGuesserUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GoldGuesserUIFigure)
        end
    end
end