classdef PlateLoaderApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlateLoaderUIFigure      matlab.ui.Figure
        SpecialFunctionPanel     matlab.ui.container.Panel
        StackAllPlatesButton     matlab.ui.control.Button
        ResponesTextArea         matlab.ui.control.TextArea
        ResponesTextAreaLabel    matlab.ui.control.Label
        GripperImage             matlab.ui.control.Image
        ExtendedImage            matlab.ui.control.Image
        Image                    matlab.ui.control.Image
        MovePlatePanel           matlab.ui.container.Panel
        MoveButton               matlab.ui.control.Button
        ToEditField              matlab.ui.control.NumericEditField
        ToLabel                  matlab.ui.control.Label
        FromEditField            matlab.ui.control.NumericEditField
        FromLabel                matlab.ui.control.Label
        SetResetTimeDelaysPanel  matlab.ui.container.Panel
        SetTimeValuesButton      matlab.ui.control.Button
        ResetTimeValuesButton    matlab.ui.control.Button
        TimeTable                matlab.ui.control.Table
        XAxisPanel               matlab.ui.container.Panel
        Button_5                 matlab.ui.control.Button
        Button_4                 matlab.ui.control.Button
        Button_3                 matlab.ui.control.Button
        Button_2                 matlab.ui.control.Button
        Button_1                 matlab.ui.control.Button
        ZAxisPanel               matlab.ui.container.Panel
        ExtendButton             matlab.ui.control.Button
        RetractButton            matlab.ui.control.Button
        GripperPanel             matlab.ui.container.Panel
        OpenButton               matlab.ui.control.Button
        CloseButton              matlab.ui.control.Button
        ResetPanel               matlab.ui.container.Panel
        ResetButton              matlab.ui.control.Button
        ConnectionPanel          matlab.ui.container.Panel
        PortNumberLabel          matlab.ui.control.Label
        PortLabel                matlab.ui.control.Label
        ConnectButton            matlab.ui.control.Button
        DisconnectButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        Robot % Description
        GripperStatusImage = ["./gripper_closed_no_plate.jpg","./gripper_open_no_plate.jpg","./gripper_with_plate.jpg"]
        GripperxLocations = [130, 215, 305, 391, 481]
        BarsxLocations = [150, 235, 325, 411, 500]
        ExtendLocation = 313;
        RetractedLocation = 429;
        BarsLocation = 362;
        
    end
    
    methods (Access = private)
        
        function xAxis(app, Xpos)
            
            app.ExtendedImage.Position(1) = app.BarsxLocations(Xpos);
            app.GripperImage.Position(1) = app.GripperxLocations(Xpos);
            app.Robot.x(Xpos);
            app.response(app.Robot.x(Xpos))
        end
        
        function reset(app)
            app.ExtendedImage.Position(1) = app.BarsxLocations(3);
            app.GripperImage.Position(1) = app.GripperxLocations(3);
            app.GripperImage.ImageSource = app.GripperStatusImage(1);
            app.ExtendedImage.Position(2) = 1000;
            app.GripperImage.Position(2) = app.RetractedLocation;
            app.Robot.reset;
            app.response(app.Robot.reset);
        end
        
        function response(app,text)
            app.ResponesTextArea.Value = string(text);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.Robot = PlateLoaderSim(2);
            app.reset;
            app.TimeTable.Data = app.Robot.defaultTimeTable(:,2:4);
            app.TimeTable.ColumnName = {'2', '3', '4'};
            app.TimeTable.RowName = {'From 1', 'From 2', 'From 3', 'From 4', 'From 5'};
            app.TimeTable.ColumnWidth = {26, 26, 26};
            app.TimeTable.FontSize = 13.14;
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            prompt = {'Enter the Com port number'};
            dlgtitle = 'Com Port';
            fieldsize = [1 40];
            definput = {'30'};
            opts.Interpreter = 'tex';
            COMPort = inputdlg(prompt,dlgtitle,fieldsize,definput,opts);
            if isempty(COMPort)
                return
            end
            app.PortNumberLabel.Text = COMPort{1};
            COMPort = str2num(COMPort{1});
            app.Robot = PlateLoader(COMPort);
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.Robot.shutdown;
            app.Robot = PlateLoaderSim(2);
            app.reset;
        end

        % Cell edit callback: TimeTable
        function TimeTableCellEdit(app, event)
            row = event.Indices(1);
            col = event.Indices(2);
            toStation = col + 1;
            newValue = event.NewData;
        
            if (row == toStation)
                fprintf('A station has no delay to itself\n');
                app.TimeTable.Data(row,col) = event.PreviousData;
                return
            end
            if (newValue < 0 || newValue ~= round(newValue))
                fprintf('Illegal time delay\n');
                app.TimeTable.Data(row,col) = event.PreviousData;
                return
            end
                       
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.GripperImage.ImageSource = app.GripperStatusImage(2);
            app.Robot.open;
            app.response(app.Robot.open);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            if(app.Robot.isPlatePresent)
                app.GripperImage.ImageSource = app.GripperStatusImage(3);
            else
                app.GripperImage.ImageSource = app.GripperStatusImage(1);
            end
            app.Robot.close;
            app.response(app.Robot.close);
        end

        % Button pushed function: RetractButton
        function RetractButtonPushed(app, event)
            app.ExtendedImage.Position(2) = 100000;
            app.GripperImage.Position(2) = app.RetractedLocation;
            app.Robot.retract
            app.response(app.Robot.retract);
           
        end

        % Button pushed function: ExtendButton
        function ExtendButtonPushed(app, event)
            app.ExtendedImage.Position(2) = app.BarsLocation;
            app.GripperImage.Position(2) = app.ExtendLocation;
            app.Robot.extend;
            app.response(app.Robot.extend);
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            app.xAxis(1);
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.xAxis(2);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.xAxis(3);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.xAxis(4);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.xAxis(5);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.reset;
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            start = app.FromEditField.Value;
            endPos = app.ToEditField.Value;
            app.Robot.movePlate(start,endPos)
            app.ExtendedImage.Position(2) = 1000;
            app.GripperImage.Position(2) = app.RetractedLocation;
            app.GripperImage.ImageSource = app.GripperStatusImage(2);
            pause(1)
            app.GripperImage.Position(1) = app.GripperxLocations(start);
            app.ExtendedImage.Position(1) = app.BarsxLocations(start);
            pause(1)
            app.GripperImage.Position(2) = app.ExtendLocation;
            app.ExtendedImage.Position(2) = app.BarsLocation;
            pause(1)
            app.GripperImage.ImageSource = app.GripperStatusImage(3);
            pause(1)
            app.ExtendedImage.Position(2) = 1000;
            app.GripperImage.Position(2) = app.RetractedLocation;
            pause(1)
            app.GripperImage.Position(1) = app.GripperxLocations(endPos);
            app.ExtendedImage.Position(1) = app.BarsxLocations(endPos);
            pause(1)
            app.GripperImage.Position(2) = app.ExtendLocation;
            app.ExtendedImage.Position(2) = app.BarsLocation;
            pause(1)
            app.GripperImage.ImageSource = app.GripperStatusImage(2);
            pause(1)
            app.ExtendedImage.Position(2) = 1000;
            app.GripperImage.Position(2) = app.RetractedLocation;
            app.response(app.Robot.movePlate(start, endPos));

        end

        % Button pushed function: ResetTimeValuesButton
        function ResetTimeValuesButtonPushed(app, event)
            app.TimeTable.Data = app.Robot.defaultTimeTable(:,2:4);
            app.response(app.Robot.setTimeValues(app.Robot.defaultTimeTable));

        end

        % Value changed function: ResponesTextArea
        function ResponesTextAreaValueChanged(app, event)
            app.ResponesTextArea.Value = string(event);
            
        end

        % Button pushed function: StackAllPlatesButton
        function StackAllPlatesButtonPushed(app, event)
            app.response(app.Robot.specialMove());
            for i = 1:4
                app.ExtendedImage.Position(2) = 1000;
                app.GripperImage.Position(2) = app.RetractedLocation;
                app.GripperImage.ImageSource = app.GripperStatusImage(2);
                pause(1)
                app.GripperImage.Position(1) = app.GripperxLocations(i);
                app.ExtendedImage.Position(1) = app.BarsxLocations(i);
                pause(1)
                app.GripperImage.Position(2) = app.ExtendLocation;
                app.ExtendedImage.Position(2) = app.BarsLocation;
                pause(1)
                app.GripperImage.ImageSource = app.GripperStatusImage(3);
                pause(1)
                app.ExtendedImage.Position(2) = 1000;
                app.GripperImage.Position(2) = app.RetractedLocation;
                pause(1)
                app.GripperImage.Position(1) = app.GripperxLocations(5);
                app.ExtendedImage.Position(1) = app.BarsxLocations(5);
                pause(1)
                app.GripperImage.ImageSource = app.GripperStatusImage(2);
                
            end
            
        end

        % Button pushed function: SetTimeValuesButton
        function SetTimeValuesButtonPushed(app, event)
            fullTable = zeros(5,5);
            fullTable(:,2:4) = app.TimeTable.Data;
            app.response(app.Robot.setTimeValues(fullTable)); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PlateLoaderUIFigure = uifigure('Visible', 'off');
            app.PlateLoaderUIFigure.Name = 'Plate Loader';
            app.ConnectionPanel = uipanel(app.PlateLoaderUIFigure);
            app.ConnectionPanel.Title = 'Connection';
            app.DisconnectButton = uibutton(app.ConnectionPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.ConnectButton = uibutton(app.ConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.PortLabel = uilabel(app.ConnectionPanel);
            app.PortLabel.Text = 'Port #';
            app.PortNumberLabel = uilabel(app.ConnectionPanel);
            app.PortNumberLabel.Text = '';
            app.ResetPanel = uipanel(app.PlateLoaderUIFigure);
            app.ResetPanel.Title = 'Reset ';
            app.ResetButton = uibutton(app.ResetPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GripperPanel = uipanel(app.PlateLoaderUIFigure);
            app.GripperPanel.Title = 'Gripper';
            app.CloseButton = uibutton(app.GripperPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.GripperPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.ZAxisPanel = uipanel(app.PlateLoaderUIFigure);
            app.ZAxisPanel.Title = 'Z - Axis';
            app.RetractButton = uibutton(app.ZAxisPanel, 'push');
            app.RetractButton.ButtonPushedFcn = createCallbackFcn(app, @RetractButtonPushed, true);
            app.RetractButton.Text = 'Retract';
            app.ExtendButton = uibutton(app.ZAxisPanel, 'push');
            app.ExtendButton.ButtonPushedFcn = createCallbackFcn(app, @ExtendButtonPushed, true);
            app.ExtendButton.Text = 'Extend';
            app.XAxisPanel = uipanel(app.PlateLoaderUIFigure);
            app.XAxisPanel.Title = 'X -  Axis';
            app.Button_1 = uibutton(app.XAxisPanel, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.XAxisPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.XAxisPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.XAxisPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.XAxisPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.SetResetTimeDelaysPanel = uipanel(app.PlateLoaderUIFigure);
            app.SetResetTimeDelaysPanel.Title = 'Set/Reset Time Delays';
            app.TimeTable = uitable(app.SetResetTimeDelaysPanel);
            app.TimeTable.ColumnName = {'1'; '2'; '3'; '4'; '5'};
            app.TimeTable.RowName = {'1'; '2'; '3'; '4'; '5'};
            app.TimeTable.ColumnEditable = true;
            app.TimeTable.CellEditCallback = createCallbackFcn(app, @TimeTableCellEdit, true);
            app.ResetTimeValuesButton = uibutton(app.SetResetTimeDelaysPanel, 'push');
            app.ResetTimeValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTimeValuesButtonPushed, true);
            app.ResetTimeValuesButton.Text = 'Reset Time Values';
            app.SetTimeValuesButton = uibutton(app.SetResetTimeDelaysPanel, 'push');
            app.SetTimeValuesButton.ButtonPushedFcn = createCallbackFcn(app, @SetTimeValuesButtonPushed, true);
            app.SetTimeValuesButton.Text = 'Set Time Values';
            app.MovePlatePanel = uipanel(app.PlateLoaderUIFigure);
            app.MovePlatePanel.Title = 'Move Plate';
            app.FromLabel = uilabel(app.MovePlatePanel);
            app.FromLabel.Text = 'From:     ';
            app.FromEditField = uieditfield(app.MovePlatePanel, 'numeric');
            app.ToLabel = uilabel(app.MovePlatePanel);
            app.ToLabel.Text = 'To:     ';
            app.ToEditField = uieditfield(app.MovePlatePanel, 'numeric');
            app.MoveButton = uibutton(app.MovePlatePanel, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.Image = uiimage(app.PlateLoaderUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'robot_background.jpg');
            app.ExtendedImage = uiimage(app.PlateLoaderUIFigure);
            app.ExtendedImage.ImageSource = fullfile(pathToMLAPP, 'extended_bars.jpg');
            app.GripperImage = uiimage(app.PlateLoaderUIFigure);
            app.GripperImage.ImageSource = fullfile(pathToMLAPP, 'gripper_with_plate.jpg');
            app.ResponesTextAreaLabel = uilabel(app.PlateLoaderUIFigure);
            app.ResponesTextAreaLabel.Text = 'Respones';
            app.ResponesTextArea = uitextarea(app.PlateLoaderUIFigure);
            app.ResponesTextArea.ValueChangedFcn = createCallbackFcn(app, @ResponesTextAreaValueChanged, true);
            app.SpecialFunctionPanel = uipanel(app.PlateLoaderUIFigure);
            app.SpecialFunctionPanel.Title = 'Special Function';
            app.StackAllPlatesButton = uibutton(app.SpecialFunctionPanel, 'push');
            app.StackAllPlatesButton.ButtonPushedFcn = createCallbackFcn(app, @StackAllPlatesButtonPushed, true);
            app.StackAllPlatesButton.Text = 'Stack All Plates';
            % Show the figure after all components are created
            app.PlateLoaderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlateLoaderApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlateLoaderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlateLoaderUIFigure)
        end
    end
end