classdef LedOnOff < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LEDONOFFUIFigure  matlab.ui.Figure
        LEDOFFButton      matlab.ui.control.Button
        LEDONButton       matlab.ui.control.Button
        LEDONOFFLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        SerialObj
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.SerialObj = serialport("COM7",19200,"Timeout",15);
        end

        % Button pushed function: LEDONButton
        function LEDONButtonPushed(app, event)
            fprintf("You pressed the ON button\n");
            flush(app.SerialObj)
            writeline(app.SerialObj,"LED ON")
            response = readline(app.SerialObj)
        end

        % Button pushed function: LEDOFFButton
        function LEDOFFButtonPushed(app, event)
            fprintf("You pressed the OFF button\n");
            flush(app.SerialObj)
            writeline(app.SerialObj,"LED OFF")
            response = readline(app.SerialObj)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LEDONOFFUIFigure = uifigure('Visible', 'off');
            app.LEDONOFFUIFigure.Name = 'LED ON / OFF';
            app.LEDONOFFLabel = uilabel(app.LEDONOFFUIFigure);
            app.LEDONOFFLabel.Text = 'LED ON/OFF';
            app.LEDONButton = uibutton(app.LEDONOFFUIFigure, 'push');
            app.LEDONButton.ButtonPushedFcn = createCallbackFcn(app, @LEDONButtonPushed, true);
            app.LEDONButton.Text = 'LED ON';
            app.LEDOFFButton = uibutton(app.LEDONOFFUIFigure, 'push');
            app.LEDOFFButton.ButtonPushedFcn = createCallbackFcn(app, @LEDOFFButtonPushed, true);
            app.LEDOFFButton.Text = 'LED OFF';
            % Show the figure after all components are created
            app.LEDONOFFUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LedOnOff

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LEDONOFFUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LEDONOFFUIFigure)
        end
    end
end